classdef nanoFeatures < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        FiltersTab                      matlab.ui.container.Tab
        CloseButton                     matlab.ui.control.Button
        ExtraanalysisPanel              matlab.ui.container.Panel
        SilhouettemetricCheckBox        matlab.ui.control.CheckBox
        FiltersPanel                    matlab.ui.container.Panel
        ChannelalignmentCheckBox        matlab.ui.control.CheckBox
        InputtypeDropDown               matlab.ui.control.DropDown
        InputtypeLabel                  matlab.ui.control.Label
        BrowseButton                    matlab.ui.control.Button
        InputfileEditField              matlab.ui.control.EditField
        InputfileEditField_5Label       matlab.ui.control.Label
        ParametersTab                   matlab.ui.container.Tab
        RunButton                       matlab.ui.control.Button
        CloseButton_2                   matlab.ui.control.Button
        FilteringPanel                  matlab.ui.container.Panel
        MaximumpointsEditField          matlab.ui.control.NumericEditField
        MaximumpointsEditFieldLabel     matlab.ui.control.Label
        MininterclusterdistancenmEditField  matlab.ui.control.NumericEditField
        MininterclusterdistancenmEditFieldLabel  matlab.ui.control.Label
        DesiredaspectratioEditField     matlab.ui.control.NumericEditField
        AspectratioLabel                matlab.ui.control.Label
        MaximumaspectratioEditField     matlab.ui.control.NumericEditField
        MaximumaspectratioEditFieldLabel  matlab.ui.control.Label
        ParticlesizecheckPanel          matlab.ui.container.Panel
        RadiushighlimitnmEditFieldLabel  matlab.ui.control.Label
        RadiuslowlimitnmEditFieldLabel  matlab.ui.control.Label
        RadiuslowlimitnmEditField       matlab.ui.control.NumericEditField
        RadiushighlimitnmEditField      matlab.ui.control.NumericEditField
        RadiusthresholdEditField        matlab.ui.control.NumericEditField
        RadiusthresholdEditFieldLabel   matlab.ui.control.Label
        FractionoflocalizationswithinfittedsphereLabel  matlab.ui.control.Label
        DBSCANClusteringPanel           matlab.ui.container.Panel
        MinimumpointsEditField          matlab.ui.control.NumericEditField
        MinimumpointsEditFieldLabel     matlab.ui.control.Label
        ScanningdiameternmEditField     matlab.ui.control.NumericEditField
        ScanningdiameternmEditFieldLabel  matlab.ui.control.Label
        GraphsTab                       matlab.ui.container.Tab
        CloseButton_3                   matlab.ui.control.Button
        TabGroup2                       matlab.ui.container.TabGroup
        LocNPTab                        matlab.ui.container.Tab
        UIAxes4                         matlab.ui.control.UIAxes
        NPdiamTab                       matlab.ui.container.Tab
        UIAxes5                         matlab.ui.control.UIAxes
        DiamlocsTab                     matlab.ui.container.Tab
        UIAxes6                         matlab.ui.control.UIAxes
    end


    properties (Access = private)
        file = ''; % global variable for the file name
        path = ''; % global variable for the file path
        numFiles = ''; % global variable for the number of files input by the user
        dataMerge; % global variable for the merged coords
        av; % global variable for the average coordinates correction in case of channel alignment
    end

    methods (Access = private)

        function output = readFile(app, inputType,filePath,i) %idk if the result could be already the app property
            switch inputType
                case 'NIKON'
                    disp('Importing N-STORM data...');

                    data=tdfread(filePath); % Read tab delimited file, returns a struct
                    
                    try
                        channel_names = strings(length(data.Channel_Name));
                    catch
                        %Do nothing if the struct is to big to pre-allocate
                        %(it works later)
                    end

                    for i=1:length(data.Channel_Name)
                        if (string(data.Channel_Name(i,:)) == "Bead Drift Correction")
                            channel_names(i) = "-1"; %Set as the drift correction channel
                        else
                            channel_names(i) = strtrim(string(data.Channel_Name(i,:))); % Transform channel names from chars to a string and remove white spaces
                        end
                    end
                    disp("Bead Drift Correction channel has been renamed to -1");

                    if app.numFiles.Value~=1 && exist('A',"var") % if there's more than one file we would need to concat and it exists
                        if exist('tmp',"var")
                            tmp = [tmp;A]; %if it exist, append
                        else
                            tmp = A; %temporal file to save the previous channel
                        end
                    end

                    n=length(data.Channel_Name);
                    A=zeros(n,4);
                    if (i<app.numFiles.Value && app.ChannelalignmentCheckBox.Value == 1) %if it's not the last channel and channel alignment filter activated --> correct coordinates
                        disp("Cumulative average X:");
                        disp(sum(app.av(i:end,1)));
                        disp("Cumulative average Y:");
                        disp(sum(app.av(i:end,2)));

                        A(:,1)=data.X + sum(app.av(i:end,1));
                        A(:,2)=data.Y + sum(app.av(i:end,2));
                        A(:,3)=data.Frame;
                        A(:,4) = channel_names;

                    else
                        A(:,1)=data.X; %x
                        A(:,2)=data.Y;
                        A(:,3)=data.Frame;
                        A(:,4)=channel_names; % channel name
                    end
                    if exist('tmp',"var")
                        disp("Concatenating files");
                        output = [tmp;A]; %concat to previous file
                    else
                        output = A;
                    end

                case 'ONI'
                    disp('Importing ONI data...');

                    data=readmatrix(filePath);
                    data(:,2)=data(:,2)+1;          % Change starting frame from 0 to 1

                    if app.numFiles.Value~=1 && exist('A',"var") % if there's more than one file we would need to concat and it exists
                        if exist('tmp',"var")
                            tmp = [tmp;A]; %if it exist, append
                        else
                            tmp = A; %temporal file to save the previous channel
                        end
                    end

                    n=length(data);
                    A=zeros(n,4);
                    if (i<app.numFiles.Value && app.ChannelalignmentCheckBox.Value == 1) %if it's not the last channel and channel alignment filter activated --> correct coordinates
                        disp("Cumulative average X:");
                        disp(sum(app.av(i:end,1)));
                        disp("Cumulative average Y:");
                        disp(sum(app.av(i:end,2)));

                        A(:,1)=data(:,3) + sum(app.av(i:end,1)); %x
                        A(:,2)=data(:,4) + sum(app.av(i:end,2)); %y
                        A(:,3)=data(:,2); %t
                        A(:,4)=cast(data(:,1),'uint8'); % channel name
                    else
                        A(:,1)=data(:,3); %x
                        A(:,2)=data(:,4);
                        A(:,3)=data(:,2);
                        A(:,4)=cast(data(:,1),'uint8'); % channel name
                    end
                    if exist('tmp',"var")
                        disp("Concatenating files");
                        output = [tmp;A]; %concat to previous file
                    else
                        output = A;
                    end
                case 'ThunderSTORM'
                    disp('Importing ThunderSTORM data...');

                    % User can choose which data to export into the csv, thus, the reader goes by column name instead of column number
                    data = readtable(filePath); % ThunderSTORM column names (matlab adapted): 'frame'    'x_nm_'    'y_nm_' | original:    'x [nm]'    'y [nm]'
                    data.Properties.VariableNames = lower(data.Properties.VariableNames); %Change names to lowercase to avoid case-sensitive errors.

                    if app.numFiles.Value~=1 && exist('A',"var") % if there's more than one file we would need to concat and it exists
                        if exist('tmp',"var")
                            tmp = [tmp;A]; %if it exist, append
                        else
                            tmp = A; %temporal file to save the previous channel
                        end
                    end

                    n = height(data); % Get size of the matrix
                    A = zeros(n,4); % creates empty matrix with the size of the data
                    if(i<app.numFiles.Value && app.ChannelalignmentCheckBox.Value == 1) %if it's not the last channel and channel alignment filter activated --> correct coordinates
                        disp("Cumulative average X:");
                        disp(sum(app.av(i:end,1)));
                        disp("Cumulative average Y:");
                        disp(sum(app.av(i:end,2)));

                        A(:,1)=data.x_nm_ + sum(app.av(i:end,1)); %x
                        A(:,2)=data.y_nm_ + sum(app.av(i:end,2)); %y
                        A(:,3)=data.frame; %t
                        if (exist('data.Channel', 'var'))
                            A(:,4)=data.Channel; %  channel name (id)
                        else
                            A(:,4)=i*ones(height(data),1)-1; % channel name (id) --> Creates a column with the value in i (file that is reading at the moment) minus 1 to match the original ONI naming (0, 1)
                        end
                    else % if it's the last channel we don't correct the coordinates
                        A(:,1)=data.x_nm_; % X
                        A(:,2)=data.y_nm_; % Y
                        A(:,3)=data.frame; % T
                        if (any(ismember(data.Properties.VariableNames,'channel'))) %if the table contains the channel info column
                            A(:,4)=data.channel; %  channel name (id)
                        else %Otherwise we assign the channel name
                            A(:,4)=i*ones(height(data),1)-1; %  channel name (id)
                        end
                    end
                    if exist('tmp',"var") % Concat to file if it already exists
                        disp("Concatenating files");
                        output = [tmp;A];
                    else
                        output = A; % First time, only one channel, no need to concat
                    end
            end
        end

        function channelAlignment(app) % Returns average array
            disp('Channel alignment filter...');

            %%% READ FIDUCIALS FILES %%%%
            disp('Reading fiducials files...');
            title = "Select 1 FOV of fiducials for each channel";
            fidCoords = {}; %Create empty cell array to save the distances calculated in each field of view
            [fidFile,fidPath] = uigetfile('*.*',title,'MultiSelect', 'on'); %open dialog to select all kind of files (all extensions)

            for i=1:length(fidFile) %save fiducial coordinates for all 3 channels
                fidCoords{i} = readmatrix(char(strcat(fidPath, fidFile(:,i)))); %Save fiducials coordinates in the cell array.
                fidCoords{i} = rmmissing(fidCoords{i}); %Remove NaN values
            end

            %%% CLUSTER FIDUCIALS %%%
            set(groot,'defaultFigureVisible','off') % Turn off figure visibility
            for i=1:length(fidCoords)
                ReadCoords(fidCoords{i},'fiducials'); %Temporal Patch to cluster fast and easily the fiducials centers without adding code to the app
                [~,~,~,massCenter{i}] = Cluster1('XYTfids.txt',80,10,100); %Call to cluster 1 for each fiducials file
                massCenter{i} = massCenter{i}.'; % Tranpose
            end
            set(groot,'defaultFigureVisible','on') % Turn on figure visibility

            %%% MATCH FIDUCIALS %%%
            disp('Matching fiducials...');
            dist = cell(massCenter); % init distance cell array
            app.av = []*(length(fidFile)-1); % init averages distances matrix
            for p=2:length(fidFile) %loop number of channels (starting in the second channel)
                % Find nearest neighbors and calculate distances
                for i=1:length(massCenter{p}) %Loop through all points in channel i as reference for the previous channel
                    k = dsearchn(massCenter{p-1}, [massCenter{p}(i,1),massCenter{p}(i,2)]);
                    dist{p-1}(i,1) = massCenter{p}(i,1) - massCenter{p-1}(k,1); %X distance
                    dist{p-1}(i,2) = massCenter{p}(i,2) - massCenter{p-1}(k,2); %Y distance
                end

                % Discard outliers
                dist{p-1} = rmoutliers(dist{p-1}); % Outliers in distance

                % Average
                if (length(dist{p-1}(:,1)) > 1)
                    app.av(p-1,1) = mean(dist{p-1}(:,1)); % Average of the X coordinates distances
                    app.av(p-1,2) = mean(dist{p-1}(:,2)); % Average of the Y coordinates distances
                else
                    app.av(p-1,1) = dist{p-1}(1,1); % Only X coordinates distance
                    app.av(p-1,2) = dist{p-1}(1,2); % Only Y coordinates distance
                end
            end
        end

        function [idx, sections, clustMembsCell647] = clustering(app, file)
            disp('Reading data...');

            %%%%% Clustering =====================================================

            Pts = [app.dataMerge(:,1) app.dataMerge(:,2)]; % Create Point matrix

            %%%% Debugger section: comment when not needed %%%
            figure('name', plus('Raw coordinates in file: ', file))
            hold on
            gscatter(Pts(:,1),Pts(:,2)); % Plot original clusters
            drawnow % Update figure real-time so the user doesn't have to wait for the end of the execution to see
            hold off
            %%%%%

            %%% Crop image into smaller sections %%%

            % Get image edges
            minX = min(Pts(:,1));
            maxX = max(Pts(:,1));
            minY = min(Pts(:,2));
            maxY = max(Pts(:,2));

            % Calculate total area
            totX = maxX - minX;
            totY = maxY - minY;

            % Calculate sections XY limits (9-section crop, starting top-left)
            sections = zeros(length(Pts), 9);

            sections(:,1) = Pts(:,1) < (minX + totX/3)                                  & Pts(:,2) > (minY + 2*(totY/3)); % Get index for coordinates in Pts inside the section
            sections(:,2) = Pts(:,1) > (minX + totX/3) & Pts(:,1) < (minX + 2*(totX/3)) & Pts(:,2) > (minY + 2*(totY/3));
            sections(:,3) =                              Pts(:,1) > (minX + 2*(totX/3)) & Pts(:,2) > (minY + 2*(totY/3));

            sections(:,4) = Pts(:,1) < (minX + totX/3)                                  & Pts(:,2) > (minY + totY/3) & Pts(:,2) < (minY + 2*(totY/3));
            sections(:,5) = Pts(:,1) > (minX + totX/3) & Pts(:,1) < (minX + 2*(totX/3)) & Pts(:,2) > (minY + totY/3) & Pts(:,2) < (minY + 2*(totY/3));
            sections(:,6) =                              Pts(:,1) > (minX + 2*(totX/3)) & Pts(:,2) > (minY + totY/3) & Pts(:,2) < (minY + 2*(totY/3));

            sections(:,7) = Pts(:,1) < (minX + totX/3)                                  & Pts(:,2) < (minY + totY/3);
            sections(:,8) = Pts(:,1) > (minX + totX/3) & Pts(:,1) < (minX + 2*(totX/3)) & Pts(:,2) < (minY + totY/3);
            sections(:,9) =                              Pts(:,1) > (minX + 2*(totX/3)) & Pts(:,2) < (minY + totY/3);

            sections = logical(sections); % Convert to logical

            %%%% Clusterig using DBSCAN
            disp('Clustering using DBSCAN...');
            tic
            idx = {}; % Create cell array to save DBSCAN results (Each section is a different size so we can't use a matrix)
            epsilon = app.ScanningdiameternmEditField.Value; % pre-allocate variables as the parallel toolbox cannot access the local app variables
            minPts = app.MinimumpointsEditField.Value;
            PtsSect = cell(size(sections,2),1); % Pre-allocate cell array to save the coordinates in each section
            for s=1:(size(sections,2))
                PtsSect{s} = Pts(sections(:,s),:); %Pre-allocate coordinates
            end

            % Create parallel pool
            cores = maxNumCompThreads; % Get the maximum number of computational threads
            if cores > 9
                threads = 9; % If there are more cores than sections, we keep the number of threads as number of sections to parallelize
            else
                threads = cores; % Otherwise we use as many cores as we have available
            end

            delete(gcp('nocreate')); % Deletes any parallel pool created in order to avoid errors.

            parpool(threads); % Initialize parallel pool
            parfor s=1:(size(sections,2))
                idx{s} = dbscan(PtsSect{s},epsilon,minPts); % Parse points filtering by section | output: cluster indices for each observation
                disp(strcat(['Found ' num2str(length(unique(idx{s}))) ' clusters in section ' num2str(s)]));
            end
            toc
        end

        function qualityFilters(app, idx, sections,file, n)

            %%% Filter by localization number and elongation ==========================

            disp('Calculating cluster features...');

            nClust = zeros(size(sections,2),1); % Pre-allocate array to save number of cluster per section
            sectionCoords = cell(size(sections,2),1); % Pre-allocate cell array to save the coordinates in each section
            locs4Clust = cell(length(nClust),size(sections,2)); %Pre-allocate cell array to save coordinates per cluster
            elongation = cell(size(sections,2),1); % initialize cell to save aspect ratio

            for s=1:(size(sections,2)) %Gets number of columns (sections)
                nClust(s) = length(unique(idx{s})); % Number of clusters
                %Group localizations by cluster
                sectionCoords{s} = app.dataMerge(sections(:,s),:); %Save coordinates for each section
                for c=1:nClust(s)-1 %One less because nClust also includes cluster -1 for outliers
                    locs4Clust{c,s} = sectionCoords{s}(idx{s}==c,:); % Save coordinates per cluster
                    meanTemp = mean(locs4Clust{c,s}); %Calculate cluster centers
                    clustCent{s}(c,:) = [meanTemp(1),meanTemp(2)];
                end

                %Flag isolated particles
                try
                    D = pdist2(clustCent{s}(:,1),clustCent{s}(:,2),'euclidean','Smallest',2); %calculate the 2 smallest distances (the smallest is 0, because comparing vector with itself)
                    isolatedClust{s} = D(2,:) >= app.MininterclusterdistancenmEditField.Value;
                catch
                    warning("No isolated clusters, going to the next image section...");
                    continue; % Go to next loop iteration
                end

                disp('Filtering...');

                idxValidClust{s} = false(nClust(s)-1,1); % init logical array to flag valid clusters
                validClust{s} = 1:1:nClust(s)-1; % intialization cluster indexes
                r=0;

                for i = 1 : nClust(s)-1 %loop to screen all clusters
                    if(size(locs4Clust{i,s},1) >= app.MinimumpointsEditField.Value) && (size(locs4Clust{i,s},1) <= app.MaximumpointsEditField.Value) %aspect ratio filter activated
                        % Filter particle based on ellipse elongation & major axis length
                        ellipse_t = fit_ellipse_app(locs4Clust{i,s}(:,1),locs4Clust{i,s}(:,2),app.DesiredaspectratioEditField.Value,app.MaximumaspectratioEditField.Value,app.RadiushighlimitnmEditField.Value*2, isolatedClust{s}(i));
                        valid = ellipse_t.valid;
                        if valid == 1
                            idxValidClust{s}(i)=true; % flag as true the valid cluster (by localiz number and elongation)
                            elongation{s}(i,1) = ellipse_t.long_axis/ellipse_t.short_axis; % Save aspect ratio
                        end
                    else
                        disp(strcat( ['#' num2str(i) ' cluster has few localizations and has been excluded']));
                        r=r+1; %count discarded clusters
                    end
                end
                %filter indexes and centers by localizations and elongation
                validClust{s} = validClust{s}(idxValidClust{s});
                clustCent{s} = clustCent{s}(idxValidClust{s},:);
                elongation{s} = elongation{s}(idxValidClust{s});
                locs_tmp = locs4Clust(:,s); %Temporal var to save the clusters for section s
                locs4Clust_filtered{:,s} = locs_tmp(idxValidClust{s}); % Filter valid clusters and save in new cell array

                % Calculate number of channels to organize the output
                channels = []; % Create empty array to save the channels ID from different cluster localizations (in case in the first cluster we are unlucky and only have 1 channel)
                for i=1:length(locs4Clust_filtered{s})
                    channels = [channels;locs4Clust_filtered{s}{i}(:,4)]; % Concat the channel IDs from each cluster
                end
                name_channels = unique(channels); % Save unique channel IDs

                disp(strcat( [num2str(nClust(s)-1-length(validClust{s})-r) ' clusters have been excluded by fit_ellipse due to unrealistic size or elongation']));
            end

            clearvars locs4Clust locs_tmp % Clear unfiltered cell arrays to save memory space

            % Initialize output file:
            try
                headers = ["Diameter","Aspect ratio","Cluster localizations"]; % Array with the headers for the features output
                for i=1:numel(name_channels)
                    title = sprintf('channel %s localizations', num2str(i));
                    headers = [headers, title];
                end
                filename = sprintf('features_%s.csv', file);
                writematrix(headers, filename); % Initialize output csv file with headers
            catch
                warning("No clusters were found in your image, finishing execution...")
                figure('name', plus('Original detected clusters by DBSCAN in file: ', file))
                gscatter(sectionCoords{1}(:,1),sectionCoords{1}(:,2),idx{1}); % Plot original clusters
                drawnow % Update figure real-time so the user doesn't have to wait for the end of the execution to see
                return; % finish app execution
            end

            %%% Size Check
            %%% ===================================================================================
            %this performs a solid-sphere circle-fitting of the clusters to calculate the radius
            %comprising a fraction of the localization, NPs with unrealistic size are discarded

            center = cell(size(sections,2),1);
            radius = cell(size(sections,2),1);
            rCheck = cell(size(sections,2),1);

            for s=1:(size(sections,2)) %Gets number of columns (sections)
                idx2remove{s}=true(length(validClust{s}),1); % logical array to remove the index of discarded clusters
                discard=0; % flag to discard a cluster

                for i=1:length(validClust{s}) %loop to screen all previously filtered clusters
                    actualClust = locs4Clust_filtered{s}(i,1); % Retrieve cluster data from nested cell array
                    clusterCoords = actualClust{1}(:,1:2); % temporal var to extract XY coords of each cluster
                    iniCenter = [mean(clusterCoords(:,1)) mean(clusterCoords(:,2))]; % mean coord as initial center guess
                    covMat = cov(clusterCoords); % calculate covariance matrix
                    iniRadius = 1.5*mean([sqrt(covMat(1,1)) sqrt(covMat(2,2))]); % initial guess of radius (1.5x the stDev)

                    % Now the location of the center of the smallest sphere encompassing p% of the datapoints is determined.
                    CenterSampleSizeAz = 10;
                    CenterSampleSizeRad = 4;
                    CenterLocation = zeros(CenterSampleSizeAz+1,2,CenterSampleSizeRad);
                    TrackLocNumber = zeros(CenterSampleSizeAz+1,CenterSampleSizeRad);

                    for m=1:CenterSampleSizeRad % screen circles of increasing radius
                        t2 = linspace(0,2*pi,CenterSampleSizeAz);
                        XCurrentCircle = 0.05*m*iniRadius*cos(t2)+iniCenter(1); % Xcoords of an approx circle with initial center and increasing radius
                        YCurrentCircle = 0.05*m*iniRadius*sin(t2)+iniCenter(2); % Ycoords of an approx circle with initial center and increasing radius
                        for o1=1:CenterSampleSizeAz % screen angular directions
                            CenterLocation(o1,:,m) = [XCurrentCircle(o1) YCurrentCircle(o1)]; % coordinate of each (XY) point of the circle, different angular directions
                            LocDistCheck = find(((clusterCoords(:,1)-XCurrentCircle(o1)).^2+(clusterCoords(:,2)-YCurrentCircle(o1)).^2) < iniRadius^2); % for each point (radius and angular direct), see if better than intial radius
                            TrackLocNumber(o1,m) = length(LocDistCheck); % for each (radius and angular dir): how many local of cluster are closer if compared to initial rad
                        end
                    end

                    RefLocCheck = find(((clusterCoords(:,1)-iniCenter(1)).^2+(clusterCoords(:,2)-iniCenter(2)).^2) < iniRadius^2); % find distances from initial guess center shorter than initial guess radius
                    RefLocNumber = length(RefLocCheck); % score of reference: how many local of cluster within initial guess radius
                    MaxLoc = max(max(TrackLocNumber)); % max score for each point (m-radius and o1-angular dir) previously screeened
                    if MaxLoc > RefLocNumber % if better than initial guess
                        [I,J] = find(TrackLocNumber==MaxLoc); % find the indexes corresponding to angular direction (o1) and radius (m) screened having max score
                        MaxLocMinRadTemp = [I J]; % store the indexes of the angular dir and radius
                        MaxLocMinRadInd = (J==min(J)); % find(J==min(J)); % logical label the indexes (o1-angular dir and m-radius) with smallest radius (m)
                        MaxLocMinRad = MaxLocMinRadTemp(MaxLocMinRadInd,:); % filter to select indexes (o1-angular dir and m-radius) with smallest radius (m)
                        finalCenter = mean(CenterLocation(MaxLocMinRad(:,1),:,min(J)),1); % move the circle center in the new position
                    else
                        finalCenter = iniCenter;
                    end

                    RadiusVec = linspace(0,3*iniRadius,1500);
                    RadiusNumLoc = zeros(length(RadiusVec),1);
                    for p=1:length(RadiusVec) % more accurate screen of radius
                        LocDistCheckRad = find(((clusterCoords(:,1)-finalCenter(1)).^2+(clusterCoords(:,2)-finalCenter(2)).^2) < RadiusVec(p)^2); % indexes of cluster localization within radius
                        RadiusNumLoc(p) = length(LocDistCheckRad); % for each screened radius a score: number of localizations within it
                    end

                    RadTrack = 1;
                    while RadiusNumLoc(RadTrack) < app.RadiusthresholdEditField.Value * size(clusterCoords,1) % this loop find which of the screened radius has FractThresh of cluster localizations
                        RadTrack = RadTrack+1;
                    end
                    finalRadius = RadiusVec(RadTrack); % the final radius is set to the minimum comprising FractThresh of cluster localizations

                    % fore each cluster, set new values of center and radius
                    center{s}(i,:) = finalCenter';
                    rCheck{s}(i) = finalRadius;

                    % a final check that radius falls within a realistic range
                    if rCheck{s}(i) >= app.RadiuslowlimitnmEditField.Value && rCheck{s}(i) <= app.RadiushighlimitnmEditField.Value
                        radius{s}(i)=finalRadius;
                    else
                        disp(['Cluster #' num2str(i) ' from section #' num2str(s) ' has been excluded from the analysis due to an unrealistic size: ' num2str(round(rCheck{s}(i))*2) 'nm diameter.'])
                        idx2remove{s}(i) = false; % collect the indexes of the clusters to remove
                        discard=discard+1;
                    end
                end

                disp(strcat(['Identified ' num2str(length(validClust{s})-discard) ' valid nanoparticles from ' num2str(length(validClust{s})) ' candidate nanoparticles']));

                try
                NPselect = radius{s}~=0; % Filter 0 values in the radius
                radius{s} = radius{s}(NPselect);
                center{s} = [center{s}(NPselect,1), center{s}(NPselect,2)];
                catch
                    warning("No nanoparticles identified in this section. Moving on to the next...");
                    continue;
                end

                clustCent{s} = clustCent{s}(idx2remove{s},:); % remove discarded clusters, after size check
                elongation{s} = elongation{s}(idx2remove{s}); % Remove discarded cluster from feature
                locs_tmp = locs4Clust_filtered(:,s); %Temporal var to save the clusters for section s
                locs4Clust_filtered{:,s} = locs_tmp{1}(idx2remove{s}); % Filter valid clusters and save in new cell array

                % Export calculated features
                disp('Creating output...');

                % Statistics and export:
                % csv: diameter | Elongation | Total localizations | (channel locs)

                diam{s} = radius{s}*2;

                % Calculate total localizations per cluster:
                locs_total = []; % Initialize and reset the temporal var for locs
                for i=1:length(locs4Clust_filtered{s}) % Loops for each cluster
                    tmp = locs4Clust_filtered{s}(i,1); % Extract cluster cell
                    locs_total(i) = length(tmp{1}); % Count the number of locs for each cluster
                end

                out = [diam{s}.',elongation{s},locs_total.']; % Start output matrix with the static data

                % Calculate localizations per cluster:
                for j=1:numel(name_channels) % Loops for each channel
                    locs_channel = []; % Initialize and reset the temporal var for locs
                    for i=1:length(locs4Clust_filtered{s}) % Loops for each cluster
                        tmp = locs4Clust_filtered{s}(i,1); % Extract cluster cell
                        locs_channel(i) = nnz(tmp{1}(:,4) == j-1); % Count the number of "non-zero elements" that equal 4 in the id column, for each cluster (channels are id 0-n and matlab counts from 1, so we need to substract
                    end
                    out = [out, locs_channel.']; % Add locs per channel to output
                end

                writematrix(out, filename, 'WriteMode', 'append'); % Write output file

            end % End of sections loop

            %%% Plot filtered clusters ============================================================
            % displays the selected clusters in only one of the sections (1)

            section = 2; % Change section to plot

            %%% Bit to plot the selected filters in only one section:
%             try
%                 figure('name',plus('Filtered clusters for section ', num2str(section), ' for file ' file))
%                 hold on
%                 gscatter(sectionCoords{section}(:,1),sectionCoords{section}(:,2),idx{section}); % Plot original clusters
%                 drawnow % Update figure real-time so the user doesn't have to wait for the end of the execution to see
%                 plot(center{section}(:,1), center{section}(:,2), 'o', 'LineWidth',2, 'MarkerSize',20, 'Color', 'black'); % Mark the selected ones
%                 for i = 1 : length(center{section})
%                     txt1 = ['\leftarrow ' num2str(i)];
%                     text(center{section}(i,1),center{section}(i,2),txt1) % this is to visualize the label of the identified clusters
%                 end
%                 legend('off'); % disable the legend
%                 hold off
%             catch
%                 warning("No nanoparticles were identified in this section, finishing plotting.");
%                 return;
%             end

            %%% Plot clusters ======================================================================
            % displays the selected nanoparticles coloring the localizations based on the channel

            colors = winter(numel(name_channels));

            figure('name',plus('Cluster localizations by channel for file ', file))
            hold on
            for s=1:(size(sections,2))
                for i=1:length(locs4Clust_filtered{s})
                    scatter(locs4Clust_filtered{s}{i}(:,1), locs4Clust_filtered{s}{i}(:,2), 5, locs4Clust_filtered{s}{i}(:,4), 'filled');
                    colormap winter
                end
            end
            legend_labels = cell(numel(unique(channels)),1);
            h = zeros(numel(name_channels), 1);
            for j = 1:numel(name_channels)
                legend_labels{j} = sprintf('channel %d', name_channels(j)); % Create legend labels
                h(j) = patch(NaN, NaN, colors(j,:)); % Create a patch (effectively, a fake graph) to get the legend for only 2 unique data points
            end
            legend(h, num2str(unique(channels)), 'Location', 'northeast', 'String', legend_labels);
            drawnow
            hold off
            savefig(plus('Cluster localizations by channel for file ', file));

            %Graphical output in-app:
            locs_total = []; % Initialize and reset the temporal var for locs
            for i=1:length(locs4Clust_filtered{section})
                tmp = locs4Clust_filtered{section}(i,1); % Extract cluster cell
                locs_total(i) = length(tmp{1}); % Count the number of locs for each cluster
            end
            histogram(locs_total,30,'FaceColor','c','Parent', app.UIAxes4); %locs NP
            histogram(diam{section},10,'FaceColor','c','Parent', app.UIAxes5); %diam NP
            scatter(diam{section},locs_total,'filled',"cyan",'Parent', app.UIAxes6); %diam vs locs


            % Plot filtered clusters in full image
            try
                figure('name',plus('Filtered clusters for file ', file))
                hold on
                for s=1:(size(sections,2))
                    gscatter(sectionCoords{s}(:,1),sectionCoords{s}(:,2),idx{s}); % Plot original clusters
                    plot(center{s}(:,1), center{s}(:,2), 'o', 'LineWidth',2, 'MarkerSize',20, 'Color', 'black'); % Mark the selected ones
                    for i = 1 : length(center{s})
                        txt1 = ['\leftarrow ' num2str(i)];
                        text(center{s}(i,1),center{s}(i,2),txt1) % this is to visualize the label of the identified clusters
                    end
                    legend('off'); % disable the legend
                end
                drawnow
                hold off
                savefig(plus('Filtered clusters for file ', file))
            catch
                warning("Plotting of filtered full image failed for some reason :)");
                return;
            end
        end

        function densityFilter(app) %needs to be updated
            disp('This filter needs to be optimized, use ThunderSTORM for now :)');
%             % init parameters
%             d=app.ScanningdiameternmEditField.Value;     % Diameter of circle to scan in nm
%             thr=app.MinimumpointsEditField.Value;    % Number of localizations in the circle
% 
%             data = app.dataMerge;
%             data(:,end+1)=zeros; % Add extra column at the end
% 
%             for k=1:size(data,1)
%                 px=data(k,1); % Retrieve actual point
%                 py=data(k,2);
%                 dist=((data(:,1)-px).^2)+((data(:,2)-py).^2); % Calculate euclidean distance
%                 xy_area = data(any(dist<=(d/2).^2,2),:); % Get points in the threshold distance
%                 if size(xy_area,1)>=thr
%                     %Include point
%                     data(k,end)=1; % Flag on the new column
%                 end
%             end
% 
%             data = data(any(data(:,end)==1,2),:); % Get the selected points
%             data = data(:,1:end-1);
% 
%             %Save filtered localizations
%             app.dataMerge = data; % Store filtered points in the localizations array
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: RunButton
        function RunButtonPushed(app, event)
            tic
            app.numFiles.Value;
            if (app.ChannelalignmentCheckBox.Value == 1) %This will read and correct the files sequentially
                channelAlignment(app); %call to channel alignment
                for n=1:app.numFiles.Value % For each different file that the user inputs
                    if app.numFiles.Value==1 % if there's only one file then it's a character vector (read all)
                        filePath = char(strcat(app.path.Value, app.file.Value)); %File name with path for matlab to find it in the file structure
                        file = extractBefore(string(app.file.Value),'.'); %File name for the output file
                    else % if there's more than one file then it's a cell array (read only corresponding cell)
                        filePath = char(strcat(app.path.Value, app.file.Value(:,n)));
                        file = extractBefore(string(app.file.Value(:,n)),'.');
                    end
                    app.dataMerge = [app.dataMerge;readFile(app, app.InputtypeDropDown.Value, filePath,n)];
                end
%                 if (app.DensityfilterCheckBox.Value == 1)
%                     densityFilter(app);
%                 end
                disp(['Analizing file: ', file]);
                [idx, sections] = clustering(app, file); % call to DBSCAN clustering
                qualityFilters(app,idx, sections,file, n); % --> wrap into a parfor for each section
                disp("all done!");
            else %This will cluster the files independently
                for n=1:app.numFiles.Value % For each different file that the user inputs
                    if app.numFiles.Value==1 % if there's only one file then it's a character vector (read all)
                        filePath = char(strcat(app.path.Value, app.file.Value)); %File name with path for matlab to find it in the file structure
                        file = extractBefore(string(app.file.Value),'.'); %File name for the output file
                    else % if there's more than one file then it's a cell array (read only corresponding cell)
                        filePath = char(strcat(app.path.Value, app.file.Value(:,n)));
                        file = extractBefore(string(app.file.Value(:,n)),'.');
                    end
                    app.dataMerge = readFile(app, app.InputtypeDropDown.Value, filePath,n); %Read file (if it's channel aligned it will read all sequentially)
%                     if (app.DensityfilterCheckBox.Value == 1)
%                         densityFilter(app);
%                     end
                    disp(['Analizing file: ', file]);
                    [idx, sections] = clustering(app, file); % call to DBSCAN clustering
                    qualityFilters(app,idx,sections,file, n);
                    disp("all done!");
                end
            end
            toc
        end

        % Callback function: BrowseButton, InputfileEditField
        function InputfilepathEditFieldValueChanged(app, event)
            try
                [app.file.Value,app.path.Value] = uigetfile('*.*','Multiselect','on'); % add multiple files, char vector for 1 file, cell array for multiple
                drawnow();
                if app.ChannelalignmentCheckBox.Value == 1
                    app.numFiles = 1; % If we need to align channels, we will concatenate all files
                elseif iscellstr(app.file.Value) % if it's a cell array
                    app.numFiles.Value = length(app.file.Value); % user input multiple files
                    value = cast(app.file.Value(:,1),'char'); % cast to character vector to match type
                    app.InputfileEditField.Value = value; % draw filename in edit filed (only first file)
                else
                    app.numFiles.Value = 1; % user input only 1 file
                    app.InputfileEditField.Value = app.file.Value; % draw filename in edit filed
                end
            catch
                warning("No files selected");
                app.InputfileEditField.Value = ' '; % Clear text field
                drawnow();
            end

        end

        % Button pushed function: CloseButton, CloseButton_2, CloseButton_3
        function CloseButtonPushed(app, event)
            close(app.UIFigure);
        end

        % Value changed function: ScanningdiameternmEditField
        function ScanningdiameternmEditFieldValueChanged(app, event)
            app.ScanningdiameternmEditField.Value;
        end

        % Value changed function: MinimumpointsEditField
        function MinimumpointsEditFieldValueChanged(app, event)
            app.MinimumpointsEditField.Value;
        end

        % Value changed function: MaximumaspectratioEditField
        function MaximumaspectratioEditFieldValueChanged(app, event)
            app.MaximumaspectratioEditField.Value;
        end

        % Value changed function: DesiredaspectratioEditField
        function DesiredaspectratioEditFieldValueChanged(app, event)
            app.DesiredaspectratioEditField.Value;
        end

        % Value changed function: MininterclusterdistancenmEditField
        function MininterclusterdistancenmEditFieldValueChanged(app, event)
            app.MininterclusterdistancenmEditField.Value;
        end

        % Callback function: not associated with a component
        function DensityfilterCheckBoxValueChanged(app, event)
            app.DensityfilterCheckBox.Value;
        end

        % Value changed function: InputtypeDropDown
        function InputtypeDropDownValueChanged(app, event)
            app.InputtypeDropDown.Value;
        end

        % Value changed function: RadiusthresholdEditField
        function RadiusthresholdEditFieldValueChanged(app, event)
            app.RadiusthresholdEditField.Value;
        end

        % Value changed function: RadiuslowlimitnmEditField
        function RadiuslowlimitnmEditFieldValueChanged(app, event)
            app.RadiuslowlimitnmEditField.Value;
        end

        % Value changed function: RadiushighlimitnmEditField
        function RadiushighlimitnmEditFieldValueChanged(app, event)
            app.RadiushighlimitnmEditField.Value;
        end

        % Value changed function: MaximumpointsEditField
        function MaximumpointsEditFieldValueChanged(app, event)
            app.MaximumpointsEditField.Value;
        end

        % Value changed function: ChannelalignmentCheckBox
        function ChannelalignmentCheckBoxValueChanged(app, event)
            app.ChannelalignmentCheckBox.Value;
        end

        % Value changed function: SilhouettemetricCheckBox
        function SilhouettemetricCheckBoxValueChanged(app, event)
            app.SilhouettemetricCheckBox.Value;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.FiltersTab = uitab(app.TabGroup);
            app.FiltersTab.Title = 'Filters';
            app.InputfileEditField_5Label = uilabel(app.FiltersTab);
            app.InputfileEditField_5Label.Text = 'Input file:';
            app.InputfileEditField = uieditfield(app.FiltersTab, 'text');
            app.InputfileEditField.ValueChangedFcn = createCallbackFcn(app, @InputfilepathEditFieldValueChanged, true);
            app.BrowseButton = uibutton(app.FiltersTab, 'push');
            app.BrowseButton.ButtonPushedFcn = createCallbackFcn(app, @InputfilepathEditFieldValueChanged, true);
            app.BrowseButton.Text = 'Browse';
            app.InputtypeLabel = uilabel(app.FiltersTab);
            app.InputtypeLabel.Text = 'Input type:';
            app.InputtypeDropDown = uidropdown(app.FiltersTab);
            app.InputtypeDropDown.Items = {'ONI', 'NIKON', 'ThunderSTORM'};
            app.InputtypeDropDown.ValueChangedFcn = createCallbackFcn(app, @InputtypeDropDownValueChanged, true);
            app.InputtypeDropDown.Value = 'ONI';
            app.FiltersPanel = uipanel(app.FiltersTab);
            app.FiltersPanel.Title = 'Filters';
            app.ChannelalignmentCheckBox = uicheckbox(app.FiltersPanel);
            app.ChannelalignmentCheckBox.ValueChangedFcn = createCallbackFcn(app, @ChannelalignmentCheckBoxValueChanged, true);
            app.ChannelalignmentCheckBox.Text = 'Channel alignment';
            app.ExtraanalysisPanel = uipanel(app.FiltersTab);
            app.ExtraanalysisPanel.Title = 'Extra analysis';
            app.SilhouettemetricCheckBox = uicheckbox(app.ExtraanalysisPanel);
            app.SilhouettemetricCheckBox.ValueChangedFcn = createCallbackFcn(app, @SilhouettemetricCheckBoxValueChanged, true);
            app.SilhouettemetricCheckBox.Text = 'Silhouette metric';
            app.CloseButton = uibutton(app.FiltersTab, 'push');
            app.CloseButton.ButtonPushedFcn = createCallbackFcn(app, @CloseButtonPushed, true);
            app.CloseButton.Text = 'Close';
            app.ParametersTab = uitab(app.TabGroup);
            app.ParametersTab.Title = 'Parameters';
            app.DBSCANClusteringPanel = uipanel(app.ParametersTab);
            app.DBSCANClusteringPanel.Title = 'DBSCAN Clustering';
            app.ScanningdiameternmEditFieldLabel = uilabel(app.DBSCANClusteringPanel);
            app.ScanningdiameternmEditFieldLabel.Text = 'Scanning diameter (nm)';
            app.ScanningdiameternmEditField = uieditfield(app.DBSCANClusteringPanel, 'numeric');
            app.ScanningdiameternmEditField.Limits = [0 Inf];
            app.ScanningdiameternmEditField.ValueChangedFcn = createCallbackFcn(app, @ScanningdiameternmEditFieldValueChanged, true);
            app.ScanningdiameternmEditField.Value = 300;
            app.MinimumpointsEditFieldLabel = uilabel(app.DBSCANClusteringPanel);
            app.MinimumpointsEditFieldLabel.Text = 'Minimum points';
            app.MinimumpointsEditField = uieditfield(app.DBSCANClusteringPanel, 'numeric');
            app.MinimumpointsEditField.Limits = [0 Inf];
            app.MinimumpointsEditField.ValueChangedFcn = createCallbackFcn(app, @MinimumpointsEditFieldValueChanged, true);
            app.MinimumpointsEditField.Value = 20;
            app.ParticlesizecheckPanel = uipanel(app.ParametersTab);
            app.ParticlesizecheckPanel.Title = 'Particle size check';
            app.FractionoflocalizationswithinfittedsphereLabel = uilabel(app.ParticlesizecheckPanel);
            app.FractionoflocalizationswithinfittedsphereLabel.Text = 'Fraction of localizations within fitted sphere';
            app.RadiusthresholdEditFieldLabel = uilabel(app.ParticlesizecheckPanel);
            app.RadiusthresholdEditFieldLabel.Text = 'Radius threshold';
            app.RadiusthresholdEditField = uieditfield(app.ParticlesizecheckPanel, 'numeric');
            app.RadiusthresholdEditField.Limits = [0 Inf];
            app.RadiusthresholdEditField.ValueChangedFcn = createCallbackFcn(app, @RadiusthresholdEditFieldValueChanged, true);
            app.RadiusthresholdEditField.Value = 0.9;
            app.RadiushighlimitnmEditField = uieditfield(app.ParticlesizecheckPanel, 'numeric');
            app.RadiushighlimitnmEditField.Limits = [0 Inf];
            app.RadiushighlimitnmEditField.ValueChangedFcn = createCallbackFcn(app, @RadiushighlimitnmEditFieldValueChanged, true);
            app.RadiushighlimitnmEditField.Value = 400;
            app.RadiuslowlimitnmEditField = uieditfield(app.ParticlesizecheckPanel, 'numeric');
            app.RadiuslowlimitnmEditField.Limits = [0 Inf];
            app.RadiuslowlimitnmEditField.ValueChangedFcn = createCallbackFcn(app, @RadiuslowlimitnmEditFieldValueChanged, true);
            app.RadiuslowlimitnmEditField.Value = 20;
            app.RadiuslowlimitnmEditFieldLabel = uilabel(app.ParticlesizecheckPanel);
            app.RadiuslowlimitnmEditFieldLabel.Text = 'Radius low limit (nm)';
            app.RadiushighlimitnmEditFieldLabel = uilabel(app.ParticlesizecheckPanel);
            app.RadiushighlimitnmEditFieldLabel.Text = 'Radius high limit (nm)';
            app.FilteringPanel = uipanel(app.ParametersTab);
            app.FilteringPanel.Title = 'Filtering';
            app.MaximumaspectratioEditFieldLabel = uilabel(app.FilteringPanel);
            app.MaximumaspectratioEditFieldLabel.Text = 'Maximum aspect ratio';
            app.MaximumaspectratioEditField = uieditfield(app.FilteringPanel, 'numeric');
            app.MaximumaspectratioEditField.Limits = [0 Inf];
            app.MaximumaspectratioEditField.ValueChangedFcn = createCallbackFcn(app, @MaximumaspectratioEditFieldValueChanged, true);
            app.MaximumaspectratioEditField.Value = 3;
            app.AspectratioLabel = uilabel(app.FilteringPanel);
            app.AspectratioLabel.Text = 'Desired aspect ratio';
            app.DesiredaspectratioEditField = uieditfield(app.FilteringPanel, 'numeric');
            app.DesiredaspectratioEditField.Limits = [0 10];
            app.DesiredaspectratioEditField.ValueChangedFcn = createCallbackFcn(app, @DesiredaspectratioEditFieldValueChanged, true);
            app.DesiredaspectratioEditField.Value = 1;
            app.MininterclusterdistancenmEditFieldLabel = uilabel(app.FilteringPanel);
            app.MininterclusterdistancenmEditFieldLabel.Text = 'Min. inter-cluster distance (nm)';
            app.MininterclusterdistancenmEditField = uieditfield(app.FilteringPanel, 'numeric');
            app.MininterclusterdistancenmEditField.Limits = [0 Inf];
            app.MininterclusterdistancenmEditField.ValueChangedFcn = createCallbackFcn(app, @MininterclusterdistancenmEditFieldValueChanged, true);
            app.MininterclusterdistancenmEditField.Value = 300;
            app.MaximumpointsEditFieldLabel = uilabel(app.FilteringPanel);
            app.MaximumpointsEditFieldLabel.Text = 'Maximum points';
            app.MaximumpointsEditField = uieditfield(app.FilteringPanel, 'numeric');
            app.MaximumpointsEditField.ValueChangedFcn = createCallbackFcn(app, @MaximumpointsEditFieldValueChanged, true);
            app.MaximumpointsEditField.Value = 10000;
            app.CloseButton_2 = uibutton(app.ParametersTab, 'push');
            app.CloseButton_2.ButtonPushedFcn = createCallbackFcn(app, @CloseButtonPushed, true);
            app.CloseButton_2.Text = 'Close';
            app.RunButton = uibutton(app.ParametersTab, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.RunButton.Text = 'Run';
            app.GraphsTab = uitab(app.TabGroup);
            app.GraphsTab.Title = 'Graphs';
            app.TabGroup2 = uitabgroup(app.GraphsTab);
            app.LocNPTab = uitab(app.TabGroup2);
            app.LocNPTab.Title = 'Loc/NP';
            app.UIAxes4 = uiaxes(app.LocNPTab);
            title(app.UIAxes4, 'Localizations/Nanoparticle')
            xlabel(app.UIAxes4, 'Number of localizations')
            ylabel(app.UIAxes4, 'Count')
            colormap(app.UIAxes4, 'cool')
            app.NPdiamTab = uitab(app.TabGroup2);
            app.NPdiamTab.Title = 'NP diam';
            app.UIAxes5 = uiaxes(app.NPdiamTab);
            title(app.UIAxes5, 'Nanoparticle diameter')
            xlabel(app.UIAxes5, 'Diameter (nm)')
            ylabel(app.UIAxes5, 'Count')
            colormap(app.UIAxes5, 'cool')
            app.DiamlocsTab = uitab(app.TabGroup2);
            app.DiamlocsTab.Title = 'Diam/locs';
            app.UIAxes6 = uiaxes(app.DiamlocsTab);
            title(app.UIAxes6, 'Diameter vs localizations')
            xlabel(app.UIAxes6, 'Diameter (nm)')
            ylabel(app.UIAxes6, 'Number of localizations')
            colormap(app.UIAxes6, 'cool')
            app.CloseButton_3 = uibutton(app.GraphsTab, 'push');
            app.CloseButton_3.ButtonPushedFcn = createCallbackFcn(app, @CloseButtonPushed, true);
            app.CloseButton_3.Text = 'Close';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = nanoFeatures

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end